classdef histGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        UIAxes                  matlab.ui.control.UIAxes
        MeanEditFieldLabel      matlab.ui.control.Label
        MeanEditField           matlab.ui.control.NumericEditField
        MedianEditFieldLabel    matlab.ui.control.Label
        MedianEditField         matlab.ui.control.NumericEditField
        StdDevEditFieldLabel    matlab.ui.control.Label
        StdDevEditField         matlab.ui.control.NumericEditField
        NoofbinsEditFieldLabel  matlab.ui.control.Label
        NoofbinsEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        nBins = 11;  % Description
        data         % Data for histogram
    end


    methods (Access = private)

        % Menu selected function: OpenMenu
        function FileOpen(app, event)

            % Get file name
            [filename,path] = uigetfile('*.dat','Load Data File');
            if filename ~= 0 
            
               % Read data
               app.data = textread([path filename],'%f');
                           
               % Create histogram
               histogram(app.UIAxes,app.data,app.nBins);
               
               % Set axis labels
               xlabel(app.UIAxes,'\bfValue');
               ylabel(app.UIAxes,'\bfCount');
               
               % Calculate statistics
               ave = mean(app.data);
               med = median(app.data);
               sd  = std(app.data);
               n   = length(app.data);
               
               % Update fields
               app.MeanEditField.Value = ave;
               app.MedianEditField.Value = med;
               app.StdDevEditField.Value = sd;
               app.NoofbinsEditField.Value = app.nBins;
               
               % Set title
               title(app.UIAxes,['Histogram (N = ' int2str(n) ')']);
               
            end
          
        end

        % Menu selected function: ExitMenu
        function FileExit(app, event)
            close(app.UIFigure);
        end

        % Value changed function: NoofbinsEditField
        function NBins(app, event)
           app.nBins = round(app.NoofbinsEditField.Value);

           % Create histogram
           histogram(app.UIAxes,app.data,app.nBins);
           
           % Set axis labels
           xlabel(app.UIAxes,'\bfValue');
           ylabel(app.UIAxes,'\bfCount');
           
           % Calculate statistics
           ave = mean(app.data);
           med = median(app.data);
           sd  = std(app.data);
           n   = length(app.data);
           
           % Update fields
           app.MeanEditField.Value = ave;
           app.MedianEditField.Value = med;
           app.StdDevEditField.Value = sd;
           app.NoofbinsEditField.Value = app.nBins;
           
           % Set title
           title(app.UIAxes,['Histogram (N = ' int2str(n) ')']);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '&File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @FileOpen, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = '&Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @FileExit, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'x';
            app.ExitMenu.Text = 'E&xit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%5.3f';
            app.MeanEditField.Editable = 'off';
            app.MedianEditFieldLabel = uilabel(app.UIFigure);
            app.MedianEditFieldLabel.Text = 'Median';
            app.MedianEditField = uieditfield(app.UIFigure, 'numeric');
            app.MedianEditField.ValueDisplayFormat = '%5.3f';
            app.MedianEditField.Editable = 'off';
            app.StdDevEditFieldLabel = uilabel(app.UIFigure);
            app.StdDevEditFieldLabel.Text = 'Std Dev';
            app.StdDevEditField = uieditfield(app.UIFigure, 'numeric');
            app.StdDevEditField.ValueDisplayFormat = '%5.3f';
            app.StdDevEditField.Editable = 'off';
            app.NoofbinsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofbinsEditFieldLabel.Text = 'No. of bins';
            app.NoofbinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofbinsEditField.ValueDisplayFormat = '%.0f';
            app.NoofbinsEditField.ValueChangedFcn = createCallbackFcn(app, @NBins, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = histGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end