classdef plot_line < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        FileMenu     matlab.ui.container.Menu
        OpenMenu     matlab.ui.container.Menu
        ExitMenu     matlab.ui.container.Menu
        EditMenu     matlab.ui.container.Menu
        SolidMenu    matlab.ui.container.Menu
        DashedMenu   matlab.ui.container.Menu
        DottedMenu   matlab.ui.container.Menu
        DashDotMenu  matlab.ui.container.Menu
        UIAxes       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        hLine % Handle for line
    end


    methods (Access = private)

        % Menu selected function: OpenMenu
        function FileOpen(app, event)

            % Get the file to open
            [filename, pathname] = uigetfile('*.dat','Load Data');
            if filename ~= 0
            
               % Open the input file
               filename = [pathname filename];
               [fid,msg] = fopen(filename,'rt');
            
               % Check to see of the open failed.
               if fid < 0 
            
                  % There was an error--tell user.
                  str = ['File ' filename ' could not be opened.'];
                  title = 'File Open Failed';
                  uialert(app.UIFigure,str,title);
            
               else
            
                  % File opened successfully. Read the (x,y) pairs from 
                  % the input file.  Get first (x,y) pair before the
                  % loop starts.
                  [in,count] = fscanf(fid,'%g',2);
                  ii = 0;
                  
                  while ~feof(fid)
                     ii = ii + 1;
                     x(ii) = in(1);
                     y(ii) = in(2);
            
                     % Get next (x,y) pair
                     [in,count] = fscanf(fid,'%g',2);
                  end
                  
                  % Data read in.  Close file.
                  fclose(fid);
                  
                  % Now plot the data.
                  app.hLine = plot(app.UIAxes,x,y,'LineWidth',3);
                  xlabel(app.UIAxes,'x');
                  ylabel(app.UIAxes,'y');
                  grid(app.UIAxes,'on');
                                    
               end
            end            
        end

        % Menu selected function: ExitMenu
        function FileExit(app, event)
            close(app.UIFigure);
        end

        % Menu selected function: SolidMenu
        function EditSolid(app, event)
            app.hLine.LineStyle = '-';
        end

        % Menu selected function: DashedMenu
        function EditDashed(app, event)
            app.hLine.LineStyle = '--';
        end

        % Menu selected function: DottedMenu
        function EditDotted(app, event)
            app.hLine.LineStyle = ':';            
        end

        % Menu selected function: DashDotMenu
        function EditDashDot(app, event)
            app.hLine.LineStyle = '-.';            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '&File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @FileOpen, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = '&Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @FileExit, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'E&xit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = '&Edit';
            app.SolidMenu = uimenu(app.EditMenu);
            app.SolidMenu.MenuSelectedFcn = createCallbackFcn(app, @EditSolid, true);
            app.SolidMenu.Text = '&Solid';
            app.DashedMenu = uimenu(app.EditMenu);
            app.DashedMenu.MenuSelectedFcn = createCallbackFcn(app, @EditDashed, true);
            app.DashedMenu.Text = '&Dashed';
            app.DottedMenu = uimenu(app.EditMenu);
            app.DottedMenu.MenuSelectedFcn = createCallbackFcn(app, @EditDotted, true);
            app.DottedMenu.Text = 'Do&tted';
            app.DashDotMenu = uimenu(app.EditMenu);
            app.DashDotMenu.MenuSelectedFcn = createCallbackFcn(app, @EditDashDot, true);
            app.DashDotMenu.Text = 'D&ash-Dot';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = plot_line

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end