classdef temp_conversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TermperatureConversionUIFigure  matlab.ui.Figure
        DegCSliderLabel                 matlab.ui.control.Label
        DegCSlider                      matlab.ui.control.Slider
        DegreesFEditFieldLabel          matlab.ui.control.Label
        DegreesFEditField               matlab.ui.control.NumericEditField
        DegreesCEditFieldLabel          matlab.ui.control.Label
        DegreesCEditField               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        temp_c = 0   % Temperature in degrees C
        temp_f = 32  % Temperature in degrees F
    end

    methods (Access = private)
    
        function to_c(app, deg_f)
           % Convert degrees Fahrenheit to degrees C.
           app.temp_c = (5/9) * (deg_f - 32);
           app.temp_f = deg_f;
        end
        
        function to_f(app, deg_c)
            % Convert degrees Celsius to degrees Fahrenheit.
            app.temp_c = deg_c;
            app.temp_f = (9/5) * deg_c + 32;
        end
        
    end


    methods (Access = private)

        % Value changed function: DegreesFEditField
        function DegFChanged(app, event)
            value = app.DegreesFEditField.Value;
            value = max([ 32 value]);
            value = min([ 212 value]);
            to_c(app,value);
            app.DegreesFEditField.Value = app.temp_f;
            app.DegreesCEditField.Value = app.temp_c;
            app.DegCSlider.Value = app.temp_c;            
        end

        % Value changed function: DegreesCEditField
        function DegCChanged(app, event)
            value = app.DegreesCEditField.Value;
            value = max([ 0 value]);
            value = min([ 100 value]);
            to_f(app,value);
            app.DegreesFEditField.Value = app.temp_f;
            app.DegreesCEditField.Value = app.temp_c;
            app.DegCSlider.Value = app.temp_c;            
        end

        % Value changed function: DegCSlider
        function SliderChanged(app, event)
            value = app.DegCSlider.Value;
            to_f(app,value);
            app.DegreesFEditField.Value = app.temp_f;
            app.DegreesCEditField.Value = app.temp_c;
            app.DegCSlider.Value = app.temp_c;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TermperatureConversionUIFigure = uifigure;
            app.TermperatureConversionUIFigure.Name = 'Termperature Conversion';
            app.DegCSliderLabel = uilabel(app.TermperatureConversionUIFigure);
            app.DegCSliderLabel.Text = 'Deg C';
            app.DegCSlider = uislider(app.TermperatureConversionUIFigure);
            app.DegCSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChanged, true);
            app.DegreesFEditFieldLabel = uilabel(app.TermperatureConversionUIFigure);
            app.DegreesFEditFieldLabel.Text = 'Degrees F';
            app.DegreesFEditField = uieditfield(app.TermperatureConversionUIFigure, 'numeric');
            app.DegreesFEditField.ValueDisplayFormat = '%5.2f';
            app.DegreesFEditField.ValueChangedFcn = createCallbackFcn(app, @DegFChanged, true);
            app.DegreesFEditField.Value = 32;
            app.DegreesCEditFieldLabel = uilabel(app.TermperatureConversionUIFigure);
            app.DegreesCEditFieldLabel.Text = 'Degrees C';
            app.DegreesCEditField = uieditfield(app.TermperatureConversionUIFigure, 'numeric');
            app.DegreesCEditField.ValueDisplayFormat = '%5.2f';
            app.DegreesCEditField.ValueChangedFcn = createCallbackFcn(app, @DegCChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = temp_conversion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TermperatureConversionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TermperatureConversionUIFigure)
        end
    end
end